classdef BeamDeflectionLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BeamDeflectionLabUIFigure     matlab.ui.Figure
        DrTRogersMChampneysBOConnellLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        MEC326BeamDeflectionLabLabel  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        LiveControlsTab               matlab.ui.container.Tab
        EditField_2                   matlab.ui.control.NumericEditField
        EditField                     matlab.ui.control.NumericEditField
        ForcingFrequencyHzSlider      matlab.ui.control.Slider
        ForcingFrequencyHzLabel       matlab.ui.control.Label
        InputForceNSlider             matlab.ui.control.Slider
        InputForceNLabel              matlab.ui.control.Label
        ConfigureBeamTab              matlab.ui.container.Tab
        kgm3Label                     matlab.ui.control.Label
        LabConditionsCheckBox         matlab.ui.control.CheckBox
        DensityEditField              matlab.ui.control.NumericEditField
        DensityEditFieldLabel         matlab.ui.control.Label
        ModulusofElasticityEditField  matlab.ui.control.NumericEditField
        ModulusofElasticityLabel      matlab.ui.control.Label
        PaLabel                       matlab.ui.control.Label
        LengthEditField               matlab.ui.control.NumericEditField
        LengthEditFieldLabel          matlab.ui.control.Label
        mLabel_3                      matlab.ui.control.Label
        WidthEditField                matlab.ui.control.NumericEditField
        WidthEditFieldLabel           matlab.ui.control.Label
        mLabel_2                      matlab.ui.control.Label
        mLabel                        matlab.ui.control.Label
        DepthEditField                matlab.ui.control.NumericEditField
        DepthEditFieldLabel           matlab.ui.control.Label
        UPDATEMODELButton             matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% LIVE DYNAMIC PARAMETERS
        F % Magnitude of force
        omega % Excitation frequency
        fs % Sampling frequency hz
        render_nth % render only every n th frame for speed
        
        %% BEAM CONFIGURATION PARAMETERS   
        h % depth (perp. to displacement)
        b  % width (parr. to displacemt)
        L % Beam length
        E % mod. of Elasticity
        rho % Density of steel
        n % Number of modes (1st n) to consider in the discretiisation
        Q % Number of spatial points to compute the response at
        zeta_modal % modal damping ratio (fixed)
        distance_to_force % distance to the application of force from clamp
        x
        lambda
        sigma
        wn
        wn_hz
        zeta
        m
        I
        A
        B
        t % time (s)
        f_off % forcing phase offset
        W % forcing freq
        X 
        Y
        happy
        sad
        confused
        byebye
    end
    
    methods (Access = private)
        
        function [x_t, W, f_off] = get_force(~,F, W, omega, t, f_off, X)
            
            if W == omega % No change
                x_t = F*sin(2*pi*W*(t-f_off));
                return
                
            elseif X(1) * X(2) ~= 1 % Omega changed AND recent sign change
                f_off = t + f_off;
                W = omega;
                x_t = F*sin(2*pi*W*(t-f_off));
                return
                
            else % Omega changed but no recent sign cange in forcing
                x_t = F*sin(2*pi*W*(t-f_off));
                
            end %if
        end %get_force
        
        function [y_t, Y, X] = time_step(~,x_t, X, Y, A, B)
            X = [x_t, X(:, 1:end-1)]; % R shift input lags
            % generate output y(t)
            y_t = sum(fliplr(B) .* fliplr(X) ,2) - sum(fliplr(A) .* fliplr(Y), 2);
            Y = [y_t, Y(:, 1:end-1)]; % R shift output lags
        end %time_step
        
        function [c] = multiconv(~,v)
            % Convolve all row vectors in v
            c = 1;
            for i = 1:size(v,1)
                c = conv(c,v(i,:));
            end
        end %multiconv
        
        function update_model(app)
            app.m = app.b * app.h * app.rho; % mass / unit length
            app.I = (app.b^3 * app.h) / 12; % 2nd moment of area
            app.lambda = (2 *(1:app.n) - 1) * (pi / 2); % dimensionless natural freq (calc from approximation in Belvin)
            app.lambda(1:2) = [1.875 4.694]; % hardcode the first two values for better accuracy
            app.lambda = app.lambda(1:app.n);
            app.sigma = (sinh(app.lambda) - sin(app.lambda)) ./ (cosh(app.lambda) + cos(app.lambda)); % dimensionless shear stress
            app.wn = (app.lambda.^2 ./ app.L^2) * sqrt((app.E * app.I) / app.m); % natural frequency (radians)
            app.wn_hz = app.wn ./ (2*pi); %(Hz)
            app.zeta = app.zeta_modal * ones(1, app.n); % modal damping ratios
            % Modeshapes (continuous in x) (from Belvin)
            get_freq = @(x) (app.lambda' * x) ./ app.L;
            get_phi = @(x) cosh(get_freq(x)) - cos(get_freq(x)) ...
                - app.sigma' .* (sinh(get_freq(x)) - sin(get_freq(x)));
            
            %% Modelling
            app.x = linspace(0.001,app.L,app.Q); % 0.001->L because physical x=0 is clamped so dont care
            locs = find(app.x>=app.distance_to_force);
            forcing_loc_idx = locs(1);
            phi = get_phi(app.x); % nxn discrete modeshapes
            
            % terms in denominators - Common to all FRF entries
            dens =  [app.wn.^2; 2*app.zeta.*app.wn; ones(1, app.n);]'; % nx3
            % common denominator
            common_denominator = 1;
            for i = 1:app.n
                common_denominator = conv(common_denominator, dens(i,:)); % 1x(2n+1)
            end
            
            
            % terms in numerator - we only need SIMO not full MIMO (only input at 0.1m)
            numerators = cell(1,app.Q-1);
            for p = forcing_loc_idx % Single driving point
                for q = 1:app.Q
                    c = zeros(app.Q,2*(app.n-1)+1);
                    for i = 1:app.n
                        a = phi(i,p) * phi(i,q);
                        c(q,:) = a*multiconv(app,dens(setdiff(1:app.n,i),:)) + c(q,:);
                    end
                    numerators(1, q) = {fliplr(sum(c))};% Single driving point
                end
            end
            
            % Continuous time model
            H = tf(numerators', fliplr(common_denominator));
            % Now discretise in time
            Hd = c2d(H, 1/app.fs);
            Hd.Variable = 'z^-1';
            % Get parameter marticies
            app.B = (cell2mat(Hd.Numerator));
            app.A = (cell2mat(Hd.Denominator));
            app.B = app.B./app.A(:,1);
            app.A = app.A./app.A(:,1);
            app.A = app.A(:, 2:end); % trim coefficients of Y(tt)
            
            
            %Initial condidions
            app.t = 0; % time (s)
            app.f_off = 0; % forcing phase offset
            app.W = app.omega; % forcing freq
            app.X = zeros(1, (2*app.n)+1);
            app.Y = zeros(app.Q, (2*app.n));
            
            % set up plot
            plot(app.UIAxes,app.x, app.Y(:,1))
  

        end %update_model
        
        
        function run_dynamics(app)
            
            i = 0;
            
            app.happy = 1;
            app.sad = 1;
            app.confused = 1;
            
            while 1
                
                % x(t) forcing value
                [x_t, app.W, app.f_off] = get_force(app,app.F, app.W, app.omega, app.t, app.f_off, app.X);
                
                % y(t) output
                [y_t, app.Y, app.X] = time_step(app,x_t, app.X, app.Y, app.A, app.B);
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%% Plot API
                if rem(i, app.render_nth) ==0
                    plot(app.UIAxes,1000*app.x, 1000*y_t, 'linewidth', 100*app.h/2)
                    ylim(app.UIAxes,[-1e2,1e2])
                    xlim(app.UIAxes,[0 1.15*10^3 *app.L])
                    ylabel(app.UIAxes,'Lateral displacement (mm)')
                    xlabel(app.UIAxes,'Distance along beam (mm)')
                    % title(app.UIAxes,sprintf('Time: %G (s)' ,app.t))
                    drawnow
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                app.t = app.t + 1/app.fs;
                i = i + 1;
                
                % Check if UICloseFigureRequest has been called:
                
                if app.happy == 0
                    
                    % x(t) forcing value
                    [x_t, app.W, app.f_off] = get_force(app,app.F, app.W, app.omega, app.t, app.f_off, app.X);
                    
                    % y(t) output
                    [y_t, app.Y, app.X] = time_step(app,x_t, app.X, app.Y, app.A, app.B);
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%% Plot API
                    if rem(i, app.render_nth) ==0
                        plot(app.UIAxes,1000*app.x, 1000*y_t, 'linewidth', 100*app.h/2)
                        ylim(app.UIAxes,[-1e2,1e2])
                        xlim(app.UIAxes,[0 1.15*10^3 *app.L])
                        ylabel(app.UIAxes,'Lateral displacement (mm)')
                        xlabel(app.UIAxes,'Distance along beam (mm)')
                        % title(app.UIAxes,sprintf('Time: %G (s)' ,app.t))
                        drawnow
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%
                    break
                    
                end %close_req_if
                
                % Check for model update: USER INPUT
                if app.sad == 0 && app.confused == 1
                    
                    % x(t) forcing value
                    [x_t, app.W, app.f_off] = get_force(app,app.F, app.W, app.omega, app.t, app.f_off, app.X);
                    
                    % y(t) output
                    [y_t, app.Y, app.X] = time_step(app,x_t, app.X, app.Y, app.A, app.B);
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%% Plot API
                    if rem(i, app.render_nth) ==0
                        plot(app.UIAxes,1000*app.x, 1000*y_t, 'linewidth', 100*app.h/2)
                        ylim(app.UIAxes,[-1e2,1e2])
                        xlim(app.UIAxes,[0 1.15*10^3 *app.L])
                        ylabel(app.UIAxes,'Lateral displacement (mm)')
                        xlabel(app.UIAxes,'Distance along beam (mm)')
                        % title(app.UIAxes,sprintf('Time: %G (s)' ,app.t))
                        drawnow
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%
                   
                    app.h = app.DepthEditField.Value * 10^-3;
                    app.b = app.WidthEditField.Value * 10^-3;
                    app.L = app.LengthEditField.Value * 10^-3;
                    app.E = app.ModulusofElasticityEditField.Value * 10^9;
                    app.rho = app.DensityEditField.Value;
                    app.UPDATEMODELButton.Enable = 'off';
                    update_model(app)
                    
                    app.sad = 1;
                    
                    % Check for model update: 'RANDOM' VALUES
                elseif app.sad == 0 && app.confused == 0
                    
                    % x(t) forcing value
                    [x_t, app.W, app.f_off] = get_force(app,app.F, app.W, app.omega, app.t, app.f_off, app.X);
                    
                    % y(t) output
                    [y_t, app.Y, app.X] = time_step(app,x_t, app.X, app.Y, app.A, app.B);
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%% Plot API
                    if rem(i, app.render_nth) ==0
                        plot(app.UIAxes,1000*app.x, 1000*y_t, 'linewidth', 100*app.h/2)
                        ylim(app.UIAxes,[-1e2,1e2])
                        xlim(app.UIAxes,[0 1.15*10^3 *app.L])
                        ylabel(app.UIAxes,'Lateral displacement (mm)')
                        xlabel(app.UIAxes,'Distance along beam (mm)')
                        % title(app.UIAxes,sprintf('Time: %G (s)' ,app.t))
                        drawnow
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%
                   
                    app.h = 22 * 10^-3;
                    app.b = 0.8 * 10^-3;
                    app.L = 585 * 10^-3;
                    app.E = 215 * 10^9;
                    app.rho = 7200;
                    app.UPDATEMODELButton.Enable = 'off';
                    update_model(app)
                    
                    app.sad = 1;
                    app.confused = 1;
                    
                end %model update if
                
            end %while

        end %run_dynamics
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            img = 'UofS.png';
            app.Image.ImageSource = img;
            
            %% SET INITIAL LIVE DYNAMIC PARAMETERS
            app.F = app.InputForceNSlider.Value; % Magnitude of force
            app.omega = app.ForcingFrequencyHzSlider.Value; % Excitation frequency
            app.EditField.Value = app.InputForceNSlider.Value;
            app.EditField_2.Value = app.ForcingFrequencyHzSlider.Value;
            app.fs = 512; % Sampling frequency hz
            app.render_nth = 10; % render only every n th frame for speed
            
            %% BEAM CONFIGURATION PARAMETERS
            app.h = app.DepthEditField.Value * 10^(-3); % depth (perp. to displacement)
            app.b = app.WidthEditField.Value * 10^(-3); % width (parr. to displacement)
            app.L = app.LengthEditField.Value * 10^(-3); % Beam length
            app.E = app.ModulusofElasticityEditField.Value * 10^(9); % mod. of Elasticity
            app.rho = app.DensityEditField.Value; % Density of steel
            app.n = 5; % Number of modes (1st n) to consider in the discretiisation
            app.Q = 100; % Number of spatial points to compute the response at
            app.zeta_modal = 0.05; % modal damping ratio (fixed)
            app.distance_to_force = 0.1; % distance to the application of force from clamp
            
            update_model(app)
            
            run_dynamics(app)        
            
            % If UICloseFigureRequest is called:
            if app.happy == 0
                app.byebye = 1;
                BeamDeflectionLabUIFigureCloseRequest(app,1)
            end


        end

        % Value changed function: InputForceNSlider
        function InputForceNSliderValueChanged(app, event)
            app.F = app.InputForceNSlider.Value;
        end

        % Value changed function: ForcingFrequencyHzSlider
        function ForcingFrequencyHzSliderValueChanged(app, event)
            app.omega = app.ForcingFrequencyHzSlider.Value;
        end

        % Callback function
        function SamplingFrequencyHzSliderValueChanged(app, event)
            app.fs = app.SamplingFrequencyHzSlider.Value;
        end

        % Value changing function: InputForceNSlider
        function InputForceNSliderValueChanging(app, event)
            app.EditField.Value = event.Value;
        end

        % Value changing function: ForcingFrequencyHzSlider
        function ForcingFrequencyHzSliderValueChanging(app, event)
            app.EditField_2.Value = event.Value;
        end

        % Value changed function: DepthEditField
        function DepthEditFieldValueChanged(app, event)
            app.UPDATEMODELButton.Enable = 'on';
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            app.UPDATEMODELButton.Enable = 'on';
        end

        % Value changed function: LengthEditField
        function LengthEditFieldValueChanged(app, event)
            app.UPDATEMODELButton.Enable = 'on';
        end

        % Value changed function: ModulusofElasticityEditField
        function ModulusofElasticityEditFieldValueChanged(app, event)
            app.UPDATEMODELButton.Enable = 'on';
        end

        % Value changed function: DensityEditField
        function DensityEditFieldValueChanged(app, event)
            app.UPDATEMODELButton.Enable = 'on';
        end

        % Button pushed function: UPDATEMODELButton
        function UPDATEMODELButtonPushed(app, event)
            app.sad = 0;
        end

        % Value changed function: LabConditionsCheckBox
        function LabConditionsCheckBoxValueChanged(app, event)
            if app.LabConditionsCheckBox.Value == true
                app.confused = 0;
                app.UPDATEMODELButton.Enable = 'on';
                app.DepthEditField.Enable = 'off';
                app.WidthEditField.Enable = 'off';
                app.LengthEditField.Enable = 'off';
                app.ModulusofElasticityEditField.Enable  = 'off';          
                app.DensityEditField.Enable = 'off';
            elseif app.LabConditionsCheckBox.Value == false
                app.confused = 1;
                app.UPDATEMODELButton.Enable = 'on';
                app.DepthEditField.Enable = 'on';
                app.WidthEditField.Enable = 'on';
                app.LengthEditField.Enable = 'on';
                app.ModulusofElasticityEditField.Enable  = 'on';          
                app.DensityEditField.Enable = 'on';
            end 
            
        end

        % Close request function: BeamDeflectionLabUIFigure
        function BeamDeflectionLabUIFigureCloseRequest(app, event)
            app.happy = 0;
            if app.happy == 0 & app.byebye == 0
                return
            elseif app.happy == 0 & app.byebye == 1
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BeamDeflectionLabUIFigure = uifigure('Visible', 'off');
            app.BeamDeflectionLabUIFigure.Name = 'Beam Deflection Lab';
            app.BeamDeflectionLabUIFigure.CloseRequestFcn = createCallbackFcn(app, @BeamDeflectionLabUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.BeamDeflectionLabUIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.BeamDeflectionLabUIFigure);
            app.LiveControlsTab = uitab(app.TabGroup);
            app.LiveControlsTab.Title = 'Live Controls';
            app.InputForceNLabel = uilabel(app.LiveControlsTab);
            app.InputForceNLabel.Text = 'Input Force, N';
            app.InputForceNSlider = uislider(app.LiveControlsTab);
            app.InputForceNSlider.Limits = [0 80];
            app.InputForceNSlider.ValueChangedFcn = createCallbackFcn(app, @InputForceNSliderValueChanged, true);
            app.InputForceNSlider.ValueChangingFcn = createCallbackFcn(app, @InputForceNSliderValueChanging, true);
            app.InputForceNSlider.Value = 10;
            app.ForcingFrequencyHzLabel = uilabel(app.LiveControlsTab);
            app.ForcingFrequencyHzLabel.Text = {'Forcing'; 'Frequency, Hz'};
            app.ForcingFrequencyHzSlider = uislider(app.LiveControlsTab);
            app.ForcingFrequencyHzSlider.Limits = [0 80];
            app.ForcingFrequencyHzSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80];
            app.ForcingFrequencyHzSlider.MajorTickLabels = {'0', '5', '10', '15', '20', '25', '30', '35', '40', '45', '50', '55', '60', '65', '70', '75', '80', ''};
            app.ForcingFrequencyHzSlider.ValueChangedFcn = createCallbackFcn(app, @ForcingFrequencyHzSliderValueChanged, true);
            app.ForcingFrequencyHzSlider.ValueChangingFcn = createCallbackFcn(app, @ForcingFrequencyHzSliderValueChanging, true);
            app.ForcingFrequencyHzSlider.Value = 2;
            app.EditField = uieditfield(app.LiveControlsTab, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.LiveControlsTab, 'numeric');
            app.EditField_2.Editable = 'off';
            app.ConfigureBeamTab = uitab(app.TabGroup);
            app.ConfigureBeamTab.Title = 'Configure Beam';
            app.UPDATEMODELButton = uibutton(app.ConfigureBeamTab, 'push');
            app.UPDATEMODELButton.ButtonPushedFcn = createCallbackFcn(app, @UPDATEMODELButtonPushed, true);
            app.UPDATEMODELButton.Enable = 'off';
            app.UPDATEMODELButton.Text = 'UPDATE MODEL';
            app.DepthEditFieldLabel = uilabel(app.ConfigureBeamTab);
            app.DepthEditFieldLabel.Text = 'Depth';
            app.DepthEditField = uieditfield(app.ConfigureBeamTab, 'numeric');
            app.DepthEditField.LowerLimitInclusive = 'off';
            app.DepthEditField.Limits = [0 Inf];
            app.DepthEditField.ValueChangedFcn = createCallbackFcn(app, @DepthEditFieldValueChanged, true);
            app.DepthEditField.Value = 25;
            app.mLabel = uilabel(app.ConfigureBeamTab);
            app.mLabel.Text = '*10^-3 (m)';
            app.mLabel_2 = uilabel(app.ConfigureBeamTab);
            app.mLabel_2.Text = '*10^-3 (m)';
            app.WidthEditFieldLabel = uilabel(app.ConfigureBeamTab);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.ConfigureBeamTab, 'numeric');
            app.WidthEditField.LowerLimitInclusive = 'off';
            app.WidthEditField.Limits = [0 Inf];
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Value = 1;
            app.mLabel_3 = uilabel(app.ConfigureBeamTab);
            app.mLabel_3.Text = '*10^-3 (m)';
            app.LengthEditFieldLabel = uilabel(app.ConfigureBeamTab);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.ConfigureBeamTab, 'numeric');
            app.LengthEditField.LowerLimitInclusive = 'off';
            app.LengthEditField.Limits = [0 Inf];
            app.LengthEditField.ValueChangedFcn = createCallbackFcn(app, @LengthEditFieldValueChanged, true);
            app.LengthEditField.Value = 585;
            app.PaLabel = uilabel(app.ConfigureBeamTab);
            app.PaLabel.Text = '*10^9 (Pa)';
            app.ModulusofElasticityLabel = uilabel(app.ConfigureBeamTab);
            app.ModulusofElasticityLabel.Text = {'Modulus of '; 'Elasticity'};
            app.ModulusofElasticityEditField = uieditfield(app.ConfigureBeamTab, 'numeric');
            app.ModulusofElasticityEditField.LowerLimitInclusive = 'off';
            app.ModulusofElasticityEditField.Limits = [0 Inf];
            app.ModulusofElasticityEditField.ValueChangedFcn = createCallbackFcn(app, @ModulusofElasticityEditFieldValueChanged, true);
            app.ModulusofElasticityEditField.Value = 210;
            app.DensityEditFieldLabel = uilabel(app.ConfigureBeamTab);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityEditField = uieditfield(app.ConfigureBeamTab, 'numeric');
            app.DensityEditField.LowerLimitInclusive = 'off';
            app.DensityEditField.Limits = [0 Inf];
            app.DensityEditField.ValueChangedFcn = createCallbackFcn(app, @DensityEditFieldValueChanged, true);
            app.DensityEditField.Value = 7850;
            app.LabConditionsCheckBox = uicheckbox(app.ConfigureBeamTab);
            app.LabConditionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @LabConditionsCheckBoxValueChanged, true);
            app.LabConditionsCheckBox.Text = 'Lab Conditions';
            app.kgm3Label = uilabel(app.ConfigureBeamTab);
            app.kgm3Label.Text = '(kg m^-3)';
            app.MEC326BeamDeflectionLabLabel = uilabel(app.BeamDeflectionLabUIFigure);
            app.MEC326BeamDeflectionLabLabel.Text = 'MEC 326 Beam Deflection Lab ';
            app.Image = uiimage(app.BeamDeflectionLabUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UofS.png');
            app.DrTRogersMChampneysBOConnellLabel = uilabel(app.BeamDeflectionLabUIFigure);
            app.DrTRogersMChampneysBOConnellLabel.Text = 'B. O''Connell, M. Champneys, Dr T. Rogers';
            % Show the figure after all components are created
            app.BeamDeflectionLabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeamDeflectionLab

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BeamDeflectionLabUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.BeamDeflectionLabUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BeamDeflectionLabUIFigure)
        end
    end
end